classdef NFL2022Analysis_Marcos_Version < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        TeamSeasonAnalysisTab         matlab.ui.container.Tab
        Team2NameLabel                matlab.ui.control.Label
        teamDropDown                  matlab.ui.control.DropDown
        ChooseaTeamsLabel             matlab.ui.control.Label
        team2DropDown                 matlab.ui.control.DropDown
        TeamNameLabel                 matlab.ui.control.Label
        PlotTypesLabel                matlab.ui.control.Label
        TeamButtonGroup               matlab.ui.container.ButtonGroup
        TwoTeamsButton                matlab.ui.control.RadioButton
        OneTeamButton                 matlab.ui.control.RadioButton
        PlotTypeButtonGroup           matlab.ui.container.ButtonGroup
        BothButton                    matlab.ui.control.RadioButton
        QBELOButton                   matlab.ui.control.RadioButton
        TeamELOButton                 matlab.ui.control.RadioButton
        team2Logo                     matlab.ui.control.Image
        scheduleTable                 matlab.ui.control.Table
        TeamLogo                      matlab.ui.control.Image
        SeasonScheduleTeam1OnlyLabel  matlab.ui.control.Label
        ELOAxes                       matlab.ui.control.UIAxes
        WeeklyELORankingsTab          matlab.ui.container.Tab
        ByesList                      matlab.ui.control.ListBox
        TeamsWithByesListBoxLabel     matlab.ui.control.Label
        TeamsDropDown                 matlab.ui.control.DropDown
        TeamsDropDownLabel            matlab.ui.control.Label
        PrevButton                    matlab.ui.control.StateButton
        NextButton                    matlab.ui.control.StateButton
        gameWeekEditField             matlab.ui.control.NumericEditField
        GameWeeksSlider               matlab.ui.control.Slider
        GameWeeksSliderLabel          matlab.ui.control.Label
        PlotDescription               matlab.ui.control.Label
        ELOBarPlot                    matlab.ui.control.UIAxes
        GameQualityvsScoringTab       matlab.ui.container.Tab
        NoticeLabel                   matlab.ui.control.Label
        ComparetwoCheckBox            matlab.ui.control.CheckBox
        Team2Image                    matlab.ui.control.Image
        Team2Label                    matlab.ui.control.Label
        Team1Label                    matlab.ui.control.Label
        Team1Image                    matlab.ui.control.Image
        SpecificDataType2Label        matlab.ui.control.Label
        SpecificDataType2DropDown     matlab.ui.control.DropDown
        SpecificDataType1Label        matlab.ui.control.Label
        SpecificDataType1DropDown     matlab.ui.control.DropDown
        DataTypeDropDown              matlab.ui.control.DropDown
        DataTypeDropDownLabel         matlab.ui.control.Label
        TimeFrameDropDown             matlab.ui.control.DropDown
        TimeFrameDropDownLabel        matlab.ui.control.Label
        AverageQualitiesAxes          matlab.ui.control.UIAxes
        AverageScoreAxes              matlab.ui.control.UIAxes
        SingleGameAnalysisTab         matlab.ui.container.Tab
        WeekSlider                    matlab.ui.control.Slider
        WeekSliderLabel               matlab.ui.control.Label
        Result2Label                  matlab.ui.control.Label
        Result1Label                  matlab.ui.control.Label
        Team2NameLabelSingleGame      matlab.ui.control.Label
        Team1NameLabel                matlab.ui.control.Label
        GameResultsLabel              matlab.ui.control.Label
        WeekLabel                     matlab.ui.control.Label
        WeekGameDropDown              matlab.ui.control.DropDown
        WeekDropDown                  matlab.ui.control.DropDown
        WeekDropDownLabel             matlab.ui.control.Label
        SingleGameAnalysisLabel       matlab.ui.control.Label
        PieChartAxes                  matlab.ui.control.UIAxes
        Team2GameAxes                 matlab.ui.control.UIAxes
        Team1GameAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        NFLData = {};
        Metadata = {};
        ELOValues;
        QB_ELOValues; 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Load the NFL Data
            app.NFLData = readcell("NFL_ELO_2022-2023_Season.xlsx");
            [nRow, nCol] = size(app.NFLData);
            % Add a column for game week number
            app.NFLData{1, nCol + 1} = 'Week Number';
            % NFLData{2, nCol + 1} = 1;
            startGameDate = app.NFLData{2,1}; % Start of a game week
            endGameDate = startGameDate + caldays(7); % End of a game week
            gameWeek = 1; % Game week counter

            for iRow = 2:nRow
                if isbetween(app.NFLData{iRow, 1}, startGameDate, endGameDate, 'openright') % tests if the game is within the start and end date variables
                    app.NFLData{iRow, nCol + 1} = gameWeek;
                else % otherwise, move the start date to the end date and increment the week
                    startGameDate = endGameDate;
                    endGameDate = startGameDate + caldays(7);
                    gameWeek = gameWeek + 1;
                    app.NFLData{iRow, nCol + 1} = gameWeek;
                end
                if strcmp(class(app.NFLData{iRow, 4}), 'missing')
                    app.NFLData{iRow, 4} = 'N/A';
                end
                app.NFLData{iRow, 1} = char(app.NFLData{iRow, 1});
            end

            %% Loading Metadata
            app.Metadata = readcell("NFL_Team_Metadata.xlsx");

            %% Question 1 Startup Code
            % Put Team Names in The Dropdown
            app.teamDropDown.Items = app.Metadata(1:32, 2)';
            app.team2DropDown.Items = app.Metadata(1:32, 2)';

            plotTeamELO(app)
            %% Question 2 Startup Code
            barELOFunction(app);

            %% Question 3 Startup Code
            % Load Team Names in the dropdown
            app.DataTypeDropDown.Value = "Teams";
            app.SpecificDataType1Label.Text = "Team 1";
            app.SpecificDataType1DropDown.Items = app.Metadata(1:32, 2)';
            plotAverages(app)
            gameAnalysis(app)
        end

        % Value changed function: teamDropDown
        function teamDropDownValueChanged(app, event)
           %% Question 1
           plotTeamELO(app);
        end

        % Selection changed function: PlotTypeButtonGroup
        function PlotTypeButtonGroupSelectionChanged(app, event)
            %% Question 1
            plotTeamELO(app);
        end

        % Selection changed function: TeamButtonGroup
        function TeamButtonGroupSelectionChanged(app, event)
            %% Question 1
            teamNumChoice = app.TeamButtonGroup.SelectedObject.Text;
            if strcmp(teamNumChoice, "One Team")
                teamChoice = app.teamDropDown.Value;
                app.TeamNameLabel.Text = teamChoice;
                app.team2DropDown.Visible = 'off';
                app.team2Logo.Visible = 'off';
                app.Team2NameLabel.Visible = 'off';
            elseif strcmp(teamNumChoice, "Two Teams")
                app.team2DropDown.Visible = 'on';
                app.Team2NameLabel.Visible = 'on';
                app.team2Logo.Visible = 'on';
            end

            plotTeamELO(app);
        end

        % Value changed function: team2DropDown
        function team2DropDownValueChanged(app, event)
            %% Question 1
            plotTeamELO(app);
        end

        % Value changed function: TeamsDropDown
        function TeamsDropDownValueChanged(app, event)
            %% Question 2
            barELOFunction(app);
        end

        % Value changed function: GameWeeksSlider
        function GameWeeksSliderValueChanged(app, event)
            %% Question 2
            app.gameWeekEditField.Value = round(app.GameWeeksSlider.Value);
            barELOFunction(app);
        end

        % Value changed function: NextButton
        function NextButtonValueChanged(app, event)
            %% Question 2
            if app.gameWeekEditField.Value ~= 22
                app.gameWeekEditField.Value = app.gameWeekEditField.Value + 1;
                app.GameWeeksSlider.Value = app.gameWeekEditField.Value;
                barELOFunction(app);
            end
        end

        % Value changed function: PrevButton
        function PrevButtonValueChanged(app, event)
            %% Question 2
            if app.gameWeekEditField.Value ~= 1
            app.gameWeekEditField.Value = app.gameWeekEditField.Value - 1;
            app.GameWeeksSlider.Value = app.gameWeekEditField.Value;
            barELOFunction(app);
            end
        end

        % Value changed function: gameWeekEditField
        function gameWeekEditFieldValueChanged(app, event)
            %% Question 2
            app.GameWeeksSlider.Value = app.gameWeekEditField.Value;
            barELOFunction(app);
        end

        % Value changed function: TimeFrameDropDown
        function TimeFrameDropDownValueChanged(app, event)
            % Question 2
            value = app.TimeFrameDropDown.Value;
            plotAverages(app);
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            % Question 2
            value = app.DataTypeDropDown.Value;
            plotAverages(app);
        end

        % Value changed function: ComparetwoCheckBox
        function ComparetwoCheckBoxValueChanged(app, event)
            value = app.ComparetwoCheckBox.Value;
            plotAverages(app);
        end

        % Value changed function: SpecificDataType1DropDown
        function SpecificDataType1DropDownValueChanged(app, event)
            value = app.SpecificDataType1DropDown.Value;
            plotAverages(app)
        end

        % Value changed function: SpecificDataType2DropDown
        function SpecificDataType2DropDownValueChanged(app, event)
            value = app.SpecificDataType2DropDown.Value;
            plotAverages(app)
        end

        % Value changed function: WeekDropDown
        function WeekDropDownValueChanged(app, event)
            % Question 4
            value = split(app.WeekDropDown.Value);
            value = string(value(2));
            app.WeekSlider.Value = str2num(value);
            gameAnalysis(app)
        end

        % Value changed function: WeekGameDropDown
        function WeekGameDropDownValueChanged(app, event)
            value = app.WeekGameDropDown.Value;
            gameAnalysis(app)
        end

        % Value changed function: WeekSlider
        function WeekSliderValueChanged(app, event)
        value = string("Week " + num2str(round(app.WeekSlider.Value)));
        app.WeekDropDown.Value = value;
        gameAnalysis(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TeamSeasonAnalysisTab = uitab(app.TabGroup);
            app.TeamSeasonAnalysisTab.Title = 'Team Season Analysis';
            app.ELOAxes = uiaxes(app.TeamSeasonAnalysisTab);
            title(app.ELOAxes, 'ELO Rating vs Time')
            xlabel(app.ELOAxes, 'Game #')
            ylabel(app.ELOAxes, 'ELO Rating')
            zlabel(app.ELOAxes, 'Z')
            app.SeasonScheduleTeam1OnlyLabel = uilabel(app.TeamSeasonAnalysisTab);
            app.SeasonScheduleTeam1OnlyLabel.Text = 'Season Schedule (Team 1 Only)';
            app.TeamLogo = uiimage(app.TeamSeasonAnalysisTab);
            app.scheduleTable = uitable(app.TeamSeasonAnalysisTab);
            app.scheduleTable.ColumnName = {'Game Week #'; 'Date'; 'Quarterback'; 'Opponent'; 'Result'};
            app.scheduleTable.RowName = {};
            app.team2Logo = uiimage(app.TeamSeasonAnalysisTab);
            app.team2Logo.Visible = 'off';
            app.PlotTypeButtonGroup = uibuttongroup(app.TeamSeasonAnalysisTab);
            app.PlotTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.TeamELOButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.TeamELOButton.Text = 'Team ELO';
            app.QBELOButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.QBELOButton.Text = 'QB ELO';
            app.BothButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.BothButton.Text = 'Both';
            app.BothButton.Value = true;
            app.TeamButtonGroup = uibuttongroup(app.TeamSeasonAnalysisTab);
            app.TeamButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TeamButtonGroupSelectionChanged, true);
            app.OneTeamButton = uiradiobutton(app.TeamButtonGroup);
            app.OneTeamButton.Text = 'One Team';
            app.OneTeamButton.Value = true;
            app.TwoTeamsButton = uiradiobutton(app.TeamButtonGroup);
            app.TwoTeamsButton.Text = 'Two Teams';
            app.PlotTypesLabel = uilabel(app.TeamSeasonAnalysisTab);
            app.PlotTypesLabel.Text = 'Plot Types';
            app.TeamNameLabel = uilabel(app.TeamSeasonAnalysisTab);
            app.TeamNameLabel.Text = 'Team Name';
            app.team2DropDown = uidropdown(app.TeamSeasonAnalysisTab);
            app.team2DropDown.ValueChangedFcn = createCallbackFcn(app, @team2DropDownValueChanged, true);
            app.team2DropDown.Visible = 'off';
            app.ChooseaTeamsLabel = uilabel(app.TeamSeasonAnalysisTab);
            app.ChooseaTeamsLabel.Text = 'Choose a Team(s)';
            app.teamDropDown = uidropdown(app.TeamSeasonAnalysisTab);
            app.teamDropDown.Items = {'Team 1', 'Team 2', 'Team 3', 'Team 4'};
            app.teamDropDown.ValueChangedFcn = createCallbackFcn(app, @teamDropDownValueChanged, true);
            app.teamDropDown.Value = 'Team 1';
            app.Team2NameLabel = uilabel(app.TeamSeasonAnalysisTab);
            app.Team2NameLabel.Visible = 'off';
            app.Team2NameLabel.Text = 'Team Name';
            app.WeeklyELORankingsTab = uitab(app.TabGroup);
            app.WeeklyELORankingsTab.Title = 'Weekly ELO Rankings';
            app.ELOBarPlot = uiaxes(app.WeeklyELORankingsTab);
            xlabel(app.ELOBarPlot, 'Team Names')
            ylabel(app.ELOBarPlot, 'ELO Rating')
            zlabel(app.ELOBarPlot, 'Z')
            app.ELOBarPlot.XTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32];
            app.ELOBarPlot.XTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'; '31'; '32'};
            app.PlotDescription = uilabel(app.WeeklyELORankingsTab);
            app.PlotDescription.Text = 'Week 1 ELO Rating For All Teams';
            app.GameWeeksSliderLabel = uilabel(app.WeeklyELORankingsTab);
            app.GameWeeksSliderLabel.Text = 'Game Weeks';
            app.GameWeeksSlider = uislider(app.WeeklyELORankingsTab);
            app.GameWeeksSlider.Limits = [1 22];
            app.GameWeeksSlider.MajorTicks = [1 3 5 7 9 11 13 15 17 19 21];
            app.GameWeeksSlider.ValueChangedFcn = createCallbackFcn(app, @GameWeeksSliderValueChanged, true);
            app.GameWeeksSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22];
            app.GameWeeksSlider.Value = 1;
            app.gameWeekEditField = uieditfield(app.WeeklyELORankingsTab, 'numeric');
            app.gameWeekEditField.ValueChangedFcn = createCallbackFcn(app, @gameWeekEditFieldValueChanged, true);
            app.gameWeekEditField.Value = 1;
            app.NextButton = uibutton(app.WeeklyELORankingsTab, 'state');
            app.NextButton.ValueChangedFcn = createCallbackFcn(app, @NextButtonValueChanged, true);
            app.NextButton.Text = 'Next';
            app.PrevButton = uibutton(app.WeeklyELORankingsTab, 'state');
            app.PrevButton.ValueChangedFcn = createCallbackFcn(app, @PrevButtonValueChanged, true);
            app.PrevButton.Text = 'Prev';
            app.TeamsDropDownLabel = uilabel(app.WeeklyELORankingsTab);
            app.TeamsDropDownLabel.Text = 'Teams';
            app.TeamsDropDown = uidropdown(app.WeeklyELORankingsTab);
            app.TeamsDropDown.Items = {'All Teams', 'NFC', 'NFC East', 'NFC West', 'NFC North', 'NFC South', 'AFC', 'AFC West', 'AFC East', 'AFC North', 'AFC South'};
            app.TeamsDropDown.ValueChangedFcn = createCallbackFcn(app, @TeamsDropDownValueChanged, true);
            app.TeamsDropDown.Value = 'All Teams';
            app.TeamsWithByesListBoxLabel = uilabel(app.WeeklyELORankingsTab);
            app.TeamsWithByesListBoxLabel.Text = 'Teams With Byes';
            app.ByesList = uilistbox(app.WeeklyELORankingsTab);
            app.ByesList.Items = {''};
            app.ByesList.Value = '';
            app.GameQualityvsScoringTab = uitab(app.TabGroup);
            app.GameQualityvsScoringTab.Title = 'Game Quality vs Scoring';
            app.AverageScoreAxes = uiaxes(app.GameQualityvsScoringTab);
            title(app.AverageScoreAxes, 'Average Total Scores')
            xlabel(app.AverageScoreAxes, 'Week')
            ylabel(app.AverageScoreAxes, 'Average Score (Points)')
            zlabel(app.AverageScoreAxes, 'Z')
            app.AverageQualitiesAxes = uiaxes(app.GameQualityvsScoringTab);
            title(app.AverageQualitiesAxes, 'Average Game Qualities')
            xlabel(app.AverageQualitiesAxes, 'Week')
            ylabel(app.AverageQualitiesAxes, 'Average Game Qualities')
            zlabel(app.AverageQualitiesAxes, 'Z')
            app.TimeFrameDropDownLabel = uilabel(app.GameQualityvsScoringTab);
            app.TimeFrameDropDownLabel.Text = 'Time Frame';
            app.TimeFrameDropDown = uidropdown(app.GameQualityvsScoringTab);
            app.TimeFrameDropDown.Items = {'Regular Season', 'Playoffs', 'Both'};
            app.TimeFrameDropDown.ValueChangedFcn = createCallbackFcn(app, @TimeFrameDropDownValueChanged, true);
            app.TimeFrameDropDown.Value = 'Regular Season';
            app.DataTypeDropDownLabel = uilabel(app.GameQualityvsScoringTab);
            app.DataTypeDropDownLabel.Text = 'Data Type';
            app.DataTypeDropDown = uidropdown(app.GameQualityvsScoringTab);
            app.DataTypeDropDown.Items = {'All Teams', 'Teams', 'Divisions', 'Conferences'};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = 'All Teams';
            app.SpecificDataType1DropDown = uidropdown(app.GameQualityvsScoringTab);
            app.SpecificDataType1DropDown.Items = {'Teams', 'Division', 'Conference'};
            app.SpecificDataType1DropDown.ValueChangedFcn = createCallbackFcn(app, @SpecificDataType1DropDownValueChanged, true);
            app.SpecificDataType1DropDown.Value = 'Teams';
            app.SpecificDataType1Label = uilabel(app.GameQualityvsScoringTab);
            app.SpecificDataType1Label.Text = 'Team 1';
            app.SpecificDataType2DropDown = uidropdown(app.GameQualityvsScoringTab);
            app.SpecificDataType2DropDown.Items = {'Teams', 'Division', 'Conference'};
            app.SpecificDataType2DropDown.ValueChangedFcn = createCallbackFcn(app, @SpecificDataType2DropDownValueChanged, true);
            app.SpecificDataType2DropDown.Visible = 'off';
            app.SpecificDataType2DropDown.Value = 'Teams';
            app.SpecificDataType2Label = uilabel(app.GameQualityvsScoringTab);
            app.SpecificDataType2Label.Visible = 'off';
            app.SpecificDataType2Label.Text = 'Team 2';
            app.Team1Image = uiimage(app.GameQualityvsScoringTab);
            app.Team1Label = uilabel(app.GameQualityvsScoringTab);
            app.Team1Label.Text = 'Team 1';
            app.Team2Label = uilabel(app.GameQualityvsScoringTab);
            app.Team2Label.Visible = 'off';
            app.Team2Label.Text = 'Team 2';
            app.Team2Image = uiimage(app.GameQualityvsScoringTab);
            app.Team2Image.Visible = 'off';
            app.ComparetwoCheckBox = uicheckbox(app.GameQualityvsScoringTab);
            app.ComparetwoCheckBox.ValueChangedFcn = createCallbackFcn(app, @ComparetwoCheckBoxValueChanged, true);
            app.ComparetwoCheckBox.Text = 'Compare two?';
            app.NoticeLabel = uilabel(app.GameQualityvsScoringTab);
            app.NoticeLabel.Visible = 'off';
            app.NoticeLabel.Text = 'NOTICE: The Bills vs Bengals game was canceled on Week 17';
            app.SingleGameAnalysisTab = uitab(app.TabGroup);
            app.SingleGameAnalysisTab.Title = 'Single Game Analysis';
            app.Team1GameAxes = uiaxes(app.SingleGameAnalysisTab);
            title(app.Team1GameAxes, 'Title')
            ylabel(app.Team1GameAxes, 'ELO Rating')
            zlabel(app.Team1GameAxes, 'Z')
            app.Team2GameAxes = uiaxes(app.SingleGameAnalysisTab);
            title(app.Team2GameAxes, 'Title')
            ylabel(app.Team2GameAxes, 'ELO Rating')
            zlabel(app.Team2GameAxes, 'Z')
            app.PieChartAxes = uiaxes(app.SingleGameAnalysisTab);
            title(app.PieChartAxes, 'Title')
            ylabel(app.PieChartAxes, 'Y')
            zlabel(app.PieChartAxes, 'Z')
            app.SingleGameAnalysisLabel = uilabel(app.SingleGameAnalysisTab);
            app.SingleGameAnalysisLabel.Text = 'Single Game Analysis ';
            app.WeekDropDownLabel = uilabel(app.SingleGameAnalysisTab);
            app.WeekDropDownLabel.Text = 'Week';
            app.WeekDropDown = uidropdown(app.SingleGameAnalysisTab);
            app.WeekDropDown.ValueChangedFcn = createCallbackFcn(app, @WeekDropDownValueChanged, true);
            app.WeekGameDropDown = uidropdown(app.SingleGameAnalysisTab);
            app.WeekGameDropDown.ValueChangedFcn = createCallbackFcn(app, @WeekGameDropDownValueChanged, true);
            app.WeekLabel = uilabel(app.SingleGameAnalysisTab);
            app.WeekLabel.Text = 'Week 1';
            app.GameResultsLabel = uilabel(app.SingleGameAnalysisTab);
            app.GameResultsLabel.Text = 'Game Results';
            app.Team1NameLabel = uilabel(app.SingleGameAnalysisTab);
            app.Team1NameLabel.Text = 'Team 1 Name';
            app.Team2NameLabelSingleGame = uilabel(app.SingleGameAnalysisTab);
            app.Team2NameLabelSingleGame.Text = 'Team 2 Name';
            app.Result1Label = uilabel(app.SingleGameAnalysisTab);
            app.Result1Label.Text = 'Result 1';
            app.Result2Label = uilabel(app.SingleGameAnalysisTab);
            app.Result2Label.Text = 'Result 2';
            app.WeekSliderLabel = uilabel(app.SingleGameAnalysisTab);
            app.WeekSliderLabel.Text = 'Week Slider';
            app.WeekSlider = uislider(app.SingleGameAnalysisTab);
            app.WeekSlider.Limits = [1 22];
            app.WeekSlider.ValueChangedFcn = createCallbackFcn(app, @WeekSliderValueChanged, true);
            app.WeekSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NFL2022Analysis_Marcos_Version

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end